classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LblSnc              matlab.ui.control.Label
        SonuLabel           matlab.ui.control.Label
        altrButton          matlab.ui.control.Button
        EitimDropDown       matlab.ui.control.DropDown
        EitimDropDownLabel  matlab.ui.control.Label
        ADropDown           matlab.ui.control.DropDown
        ADropDownLabel      matlab.ui.control.Label
        DerinrenmeDersiGsKanseriTmrTespitiUygulamasLabel  matlab.ui.control.Label
        FotorafSeButton     matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        seciliAg = "OurCNN" 
        seciliDuzey = "Augmentli"  
        seciliImage = []
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           ImageFile = imread("./loading.png");
           imagesc(app.UIAxes, ImageFile);
           app.seciliImage = ImageFile;
        end

        % Button pushed function: FotorafSeButton
        function FotoSecPushed(app, event)
            % Allow the user to select an image file
           [filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, filename];
           ImageFile = imread(fullname);
           imagesc(app.UIAxes, ImageFile);
           app.seciliImage = ImageFile;
        end

        % Value changed function: ADropDown
        function AgDropDownValueChanged(app, event)
            value = app.ADropDown.Value;
            if isequal(value,"GoogLeNet")
                app.seciliAg = "GoogLeNet";
            elseif isequal(value,"MobileNetV2")
                app.seciliAg = "Mobilenetv2";
            else
                app.seciliAg = "OurCNN";
            end
        end

        % Value changed function: EitimDropDown
        function EitimDropDownValueChanged(app, event)
            value = app.EitimDropDown.Value; 
            if isequal(value,"Augmentli")
                app.seciliDuzey = "Augmentli";  
            else
                app.seciliDuzey = "Augmentsiz"; 
            end
        end

        % Button pushed function: altrButton
        function altrButtonPushed(app, event)
            seciliAgVeDuzey = app.seciliDuzey+app.seciliAg+".mat";
            matFilePath = fullfile("NeuralNetworks",seciliAgVeDuzey); 
            loadedData = load(matFilePath);
            resizedSeciliImage = imresize(app.seciliImage,[224 224]);
            sonuc = classify(loadedData.ag,resizedSeciliImage);
            app.LblSnc.Text = sonuc; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.FotorafSeButton = uibutton(app.UIFigure, 'push');
            app.FotorafSeButton.ButtonPushedFcn = createCallbackFcn(app, @FotoSecPushed, true);
            app.FotorafSeButton.Tag = 'buttonFoto';
            app.FotorafSeButton.Icon = fullfile(pathToMLAPP, 'search.png');
            app.FotorafSeButton.Text = 'Fotoğraf Seç...';
            app.DerinrenmeDersiGsKanseriTmrTespitiUygulamasLabel = uilabel(app.UIFigure);
            app.DerinrenmeDersiGsKanseriTmrTespitiUygulamasLabel.Text = {'Derin Öğrenme Dersi'; 'Göğüs Kanseri '; 'Tümör Tespiti Uygulaması '};
            app.ADropDownLabel = uilabel(app.UIFigure);
            app.ADropDownLabel.Text = 'Ağ:';
            app.ADropDown = uidropdown(app.UIFigure);
            app.ADropDown.Items = {'GoogLeNet', 'MobileNetV2', 'OurCNN'};
            app.ADropDown.ValueChangedFcn = createCallbackFcn(app, @AgDropDownValueChanged, true);
            app.ADropDown.Value = 'OurCNN';
            app.EitimDropDownLabel = uilabel(app.UIFigure);
            app.EitimDropDownLabel.Text = 'Eğitim:';
            app.EitimDropDown = uidropdown(app.UIFigure);
            app.EitimDropDown.Items = {'Augmentli', 'Augmentsiz'};
            app.EitimDropDown.ValueChangedFcn = createCallbackFcn(app, @EitimDropDownValueChanged, true);
            app.EitimDropDown.Value = 'Augmentli';
            app.altrButton = uibutton(app.UIFigure, 'push');
            app.altrButton.ButtonPushedFcn = createCallbackFcn(app, @altrButtonPushed, true);
            app.altrButton.Icon = fullfile(pathToMLAPP, 'play-button.png');
            app.altrButton.Text = 'Çalıştır';
            app.SonuLabel = uilabel(app.UIFigure);
            app.SonuLabel.Text = 'Sonuç:';
            app.LblSnc = uilabel(app.UIFigure);
            app.LblSnc.Tag = 'LblSnc';
            app.LblSnc.Text = 'bekleniyor...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end